classdef OpenHype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopButton                      matlab.ui.control.Button
        OpenUSBcamoreviewButton         matlab.ui.control.Button
        AutoconnectallButton            matlab.ui.control.Button
        ContinuousscanButton            matlab.ui.control.StateButton
        AspectnumberEditField           matlab.ui.control.NumericEditField
        AspectnumberEditFieldLabel      matlab.ui.control.Label
        LightintensityadjustmentSlider  matlab.ui.control.Slider
        LightintensityadjustmentSliderLabel  matlab.ui.control.Label
        ScanupperlimitSlider            matlab.ui.control.Slider
        ScanupperlimitSliderLabel       matlab.ui.control.Label
        ReleasemirrorButton             matlab.ui.control.Button
        TestDACButton                   matlab.ui.control.Button
        ManualdiflectionSlider          matlab.ui.control.Slider
        ManualdiflectionSliderLabel     matlab.ui.control.Label
        MirrordiflectionGauge           matlab.ui.control.LinearGauge
        MirrordiflectionGaugeLabel      matlab.ui.control.Label
        Lamp3                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp1                           matlab.ui.control.Lamp
        SavehypercubeButton             matlab.ui.control.Button
        ConnecttocameraButton           matlab.ui.control.Button
        OnlineprocessingButton          matlab.ui.control.StateButton
        SpatiosepctralpreviewButton     matlab.ui.control.StateButton
        USBCameraDropDown               matlab.ui.control.DropDown
        USBCameraDropDownLabel          matlab.ui.control.Label
        ConnecttocontrollerButton       matlab.ui.control.Button
        NumberoflinesEditField          matlab.ui.control.NumericEditField
        NumberoflinesEditFieldLabel     matlab.ui.control.Label
        StartCaptureButton              matlab.ui.control.Button
        OpenhypehyperspectralcameracontrollerLabel  matlab.ui.control.Label
        PortNumText                     matlab.ui.control.NumericEditField
        COMportEditFieldLabel           matlab.ui.control.Label
        UIAxes_Image                    matlab.ui.control.UIAxes
        UIAxes_SnapShot                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        arduinoObj
        i2cdac
        cam
        CamName
        COMPortA
        ROIA % Region of Interest
        NlinesA % Number of lines in the scan
        CamCOMSWidthA% Number of pixels of the CMOS sensor (x-direction)
        TerminateMeas =false; % global variable to control the termination of capturing
        img; % Datacube
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("Settings.mat")
            app.NlinesA = NLines;
            app.COMPortA = COMPort;
            app.ROIA = ROI;
            app.CamName=WebCamName;
            app.PortNumText.Value = COMPort;
            app.USBCameraDropDown.Items{1} = WebCamName;
            app.NumberoflinesEditField.Value=NLines;
            app.CamCOMSWidthA = CamCOMSWidth;


        end

        % Button pushed function: ConnecttocontrollerButton
        function ConnecttocontrollerButtonPushed(app, event)
            try
                app.arduinoObj = arduino(['COM',num2str(app.PortNumText.Value)],"Uno","Libraries","I2C");
                app.i2cdac = device(app.arduinoObj,'I2CAddress','0x62','bitrate',400000);
            catch ME
                errordlg(ME.message)
            end

            disp(app.arduinoObj)%%
            pause(1);
            disp(app.i2cdac)
            app.Lamp1.Color=[0.00,1.00,0.00];
            app.TestDACButton.Enable="on";


        end

        % Button pushed function: TestDACButton
        function TestDACButtonPushed(app, event)
            disp("Sending test signal")
            writeDigitalPin(app.arduinoObj,"D13",1); % enable the mirror controller
            pause(1)
            for i=1:10
                write(app.i2cdac,ConverDAC(0))
                write(app.i2cdac,ConverDAC(4095))
            end
            writeDigitalPin(app.arduinoObj,"D13",0); % disable the mirror controller
            app.Lamp2.Color='g';
        end

        % Drop down opening function: USBCameraDropDown
        function USBCameraDropDownOpening(app, event)
            camlist = webcamlist;
            app.USBCameraDropDown.Items=camlist;
            app.CamName=app.USBCameraDropDown.Value;

        end

        % Button pushed function: ConnecttocameraButton
        function ConnecttocameraButtonPushed(app, event)
            load("Settings")
            try
                app.cam = webcam(app.CamName);
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.Brightness=Brightness;
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.Contrast=Contrast;
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.Exposure=Exposure;
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.Sharpness=Sharpness;
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.Gamma=Gamma;
            catch ME
                errordlg(ME.message)
            end

            try
                app.cam.ExposureMode='manual';
            catch ME
                errordlg(ME.message)
            end

            disp(app.cam);
            app.Lamp3.Color='g';

        end

        % Button pushed function: ReleasemirrorButton
        function ReleasemirrorButtonPushed(app, event)
            writeDigitalPin(app.arduinoObj,"D13",0); % release the mirror controller
            app.ManualdiflectionSlider.Value =0;
        end

        % Value changed function: ManualdiflectionSlider
        function ManualdiflectionSliderValueChanged(app, event)
            value = app.ManualdiflectionSlider.Value;
            writeDigitalPin(app.arduinoObj,"D13",1); % arm the mirror controller
            ADCValue = 4096-((4096-1)/(100)*(value-1));
            write(app.i2cdac,ConverDAC(ADCValue));

        end

        % Button pushed function: StartCaptureButton
        function StartCaptureButtonPushed(app, event)
            app.TerminateMeas=false;
            app.StopButton.Enable="on";
            app.SavehypercubeButton.Enable="off";
            OnlinePlot = app.OnlineprocessingButton.Value;
            writeDigitalPin(app.arduinoObj,"D13",1); % enable the mirror controller
            pause(0.5)

            NLines = app.NumberoflinesEditField.Value; % Number of lines
            ScanLimit=app.ScanupperlimitSlider.Value; % how much to scan from the sceen
            WLines = min(round(app.ROIA(4)),app.CamCOMSWidthA-app.ROIA(2)+1);
            NColors = round(app.ROIA(3));
            AspectR =500*ScanLimit;

            while(1)
                app.img=uint8(zeros(NLines,WLines,NColors));
                write(app.i2cdac,ConverDAC(0))

                for ctr=1:3 % Clear the buffer
                    sp = snapshot(app.cam);
                end


                for ctr=1:NLines

                    ADCValue = 4096-((4096*ScanLimit-1)/(NLines)*(ctr-1));
                    write(app.i2cdac,ConverDAC(ADCValue))
                    pause(0.05)
                    sp = rgb2gray(snapshot(app.cam));
                    sp=imcrop(sp,app.ROIA);
                    sp(size(app.img,2)+1:end,:)=[];
                    sp(:,size(app.img,3)+1:end)=[];
                    
                    if(app.SpatiosepctralpreviewButton.Value) % Show the 2D snapshot
                        imshow(sp,'Parent',app.UIAxes_SnapShot)
                        %colormap parula
                    end

                    app.img(ctr,:,:) = sp;
                    app.MirrordiflectionGauge.Value=ctr/NLines*100;

                    if OnlinePlot==1
                        Light= app.LightintensityadjustmentSlider.Value;
                        AA=mat2gray(squeeze(sum(app.img(:,:,:),3)));
                        if NLines<=WLines
                            AA=mat2gray(imresize(AA,size(AA).*[AspectR/NLines 1]));
                        else
                            AA=mat2gray(imresize(AA,size(AA).*[1 NLines/AspectR]));
                        end
                        AA = imadjust(AA,[0 Light]);
                        imshow(AA,'Parent',app.UIAxes_Image)
                        drawnow;
                    end
                    if (app.TerminateMeas)
                        return
                    end
                end

                write(app.i2cdac,ConverDAC(2048))


                % Test reconstracting RGB
                figure
                clear Stacked
                Red=app.img(:,:,round(2/3*NColors):end);
                Stacked(:,:,1)=mat2gray(squeeze(sum(Red,3)));
                Grn=app.img(:,:,round(1/3*NColors):round(2/3*NColors));
                Stacked(:,:,2)=mat2gray(squeeze(sum(Grn,3)));
                Blu=app.img(:,:,1:round(1/3*NColors));
                Stacked(:,:,3)=mat2gray(squeeze(sum(Blu,3)));
                Stacked = imresize(Stacked,[800*ScanLimit 600],"bilinear");
                Stacked2 = imadjust(Stacked,[0 0 0; Light Light Light],[]);
                imshow(Stacked2)
                drawnow

                if app.ContinuousscanButton.Value~=1
                    break;
                end


            end
            toc
            writeDigitalPin(app.arduinoObj,"D13",0); % disable the mirror controller
            app.SavehypercubeButton.Enable="on";
        end

        % Button pushed function: AutoconnectallButton
        function AutoconnectallButtonPushed(app, event)
            % Autostart
            ConnecttocontrollerButtonPushed(app)
            app.TestDACButton.Enable="on";
            TestDACButtonPushed(app)
            ConnecttocameraButtonPushed(app)

        end

        % Button pushed function: OpenUSBcamoreviewButton
        function OpenUSBcamoreviewButtonPushed(app, event)
            preview(app.cam)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.TerminateMeas = true;
            app.StopButton.Enable="off";
            app.SavehypercubeButton.Enable="on";
        end

        % Button pushed function: SavehypercubeButton
        function SavehypercubeButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Save hypercube');
            img=app.img;
            try
                save([path,file],"img");
            catch ME
                errordlg(ME.message)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_SnapShot = uiaxes(app.UIFigure);
            title(app.UIAxes_SnapShot, 'Camera preview')
            xlabel(app.UIAxes_SnapShot, 'z-axis')
            ylabel(app.UIAxes_SnapShot, 'x-axis')
            app.UIAxes_SnapShot.XTick = [];
            app.UIAxes_SnapShot.YTick = [];
            app.UIAxes_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Image, 'Hyper image')
            xlabel(app.UIAxes_Image, 'x-axis')
            ylabel(app.UIAxes_Image, 'y-axis')
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.PortNumText = uieditfield(app.UIFigure, 'numeric');
            app.PortNumText.Value = 21;
            app.OpenhypehyperspectralcameracontrollerLabel = uilabel(app.UIFigure);
            app.OpenhypehyperspectralcameracontrollerLabel.Text = 'Openhype: hyperspectral camera controller';
            app.StartCaptureButton = uibutton(app.UIFigure, 'push');
            app.StartCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartCaptureButtonPushed, true);
            app.StartCaptureButton.Text = 'Start Capture';
            app.NumberoflinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflinesEditFieldLabel.Text = 'Number of lines';
            app.NumberoflinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflinesEditField.Value = 50;
            app.ConnecttocontrollerButton = uibutton(app.UIFigure, 'push');
            app.ConnecttocontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttocontrollerButtonPushed, true);
            app.ConnecttocontrollerButton.Text = '1. Connect to controller';
            app.USBCameraDropDownLabel = uilabel(app.UIFigure);
            app.USBCameraDropDownLabel.Text = 'USB Camera';
            app.USBCameraDropDown = uidropdown(app.UIFigure);
            app.USBCameraDropDown.DropDownOpeningFcn = createCallbackFcn(app, @USBCameraDropDownOpening, true);
            app.SpatiosepctralpreviewButton = uibutton(app.UIFigure, 'state');
            app.SpatiosepctralpreviewButton.Text = 'Spatio-sepctral preview';
            app.SpatiosepctralpreviewButton.Value = true;
            app.OnlineprocessingButton = uibutton(app.UIFigure, 'state');
            app.OnlineprocessingButton.Text = 'Online processing';
            app.OnlineprocessingButton.Value = true;
            app.ConnecttocameraButton = uibutton(app.UIFigure, 'push');
            app.ConnecttocameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttocameraButtonPushed, true);
            app.ConnecttocameraButton.Text = '3. Connect to camera';
            app.SavehypercubeButton = uibutton(app.UIFigure, 'push');
            app.SavehypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @SavehypercubeButtonPushed, true);
            app.SavehypercubeButton.Enable = 'off';
            app.SavehypercubeButton.Text = 'Save hypercube';
            app.Lamp1 = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.MirrordiflectionGaugeLabel = uilabel(app.UIFigure);
            app.MirrordiflectionGaugeLabel.Text = 'Mirror diflection';
            app.MirrordiflectionGauge = uigauge(app.UIFigure, 'linear');
            app.ManualdiflectionSliderLabel = uilabel(app.UIFigure);
            app.ManualdiflectionSliderLabel.Text = 'Manual diflection';
            app.ManualdiflectionSlider = uislider(app.UIFigure);
            app.ManualdiflectionSlider.ValueChangedFcn = createCallbackFcn(app, @ManualdiflectionSliderValueChanged, true);
            app.TestDACButton = uibutton(app.UIFigure, 'push');
            app.TestDACButton.ButtonPushedFcn = createCallbackFcn(app, @TestDACButtonPushed, true);
            app.TestDACButton.Enable = 'off';
            app.TestDACButton.Text = '2. Test DAC';
            app.ReleasemirrorButton = uibutton(app.UIFigure, 'push');
            app.ReleasemirrorButton.ButtonPushedFcn = createCallbackFcn(app, @ReleasemirrorButtonPushed, true);
            app.ReleasemirrorButton.Text = 'Release mirror';
            app.ScanupperlimitSliderLabel = uilabel(app.UIFigure);
            app.ScanupperlimitSliderLabel.Text = 'Scan upper limit';
            app.ScanupperlimitSlider = uislider(app.UIFigure);
            app.ScanupperlimitSlider.Limits = [0 1];
            app.ScanupperlimitSlider.Value = 0.7;
            app.LightintensityadjustmentSliderLabel = uilabel(app.UIFigure);
            app.LightintensityadjustmentSliderLabel.Text = 'Light intensity adjustment';
            app.LightintensityadjustmentSlider = uislider(app.UIFigure);
            app.LightintensityadjustmentSlider.Limits = [0 1];
            app.LightintensityadjustmentSlider.Value = 0.8;
            app.AspectnumberEditFieldLabel = uilabel(app.UIFigure);
            app.AspectnumberEditFieldLabel.Text = 'Aspect number';
            app.AspectnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectnumberEditField.Limits = [0 2000];
            app.AspectnumberEditField.Value = 500;
            app.ContinuousscanButton = uibutton(app.UIFigure, 'state');
            app.ContinuousscanButton.Text = 'Continuous scan';
            app.AutoconnectallButton = uibutton(app.UIFigure, 'push');
            app.AutoconnectallButton.ButtonPushedFcn = createCallbackFcn(app, @AutoconnectallButtonPushed, true);
            app.AutoconnectallButton.Text = 'Auto connect all';
            app.OpenUSBcamoreviewButton = uibutton(app.UIFigure, 'push');
            app.OpenUSBcamoreviewButton.ButtonPushedFcn = createCallbackFcn(app, @OpenUSBcamoreviewButtonPushed, true);
            app.OpenUSBcamoreviewButton.Text = 'Open USB cam oreview';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenHype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end