classdef OpenHypeSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Lamp1_2                      matlab.ui.control.Lamp
        Lamp1                        matlab.ui.control.Lamp
        ManualdiflectionSlider       matlab.ui.control.Slider
        ManualdiflectionSliderLabel  matlab.ui.control.Label
        ConnecttocontrollerButton    matlab.ui.control.Button
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        StopconnectionButton         matlab.ui.control.Button
        WidthpixelsEditField         matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel    matlab.ui.control.Label
        ConnecttocameraButton        matlab.ui.control.Button
        SavesettingsButton           matlab.ui.control.Button
        ROIHeightSlider              matlab.ui.control.Slider
        ROIHeightSliderLabel         matlab.ui.control.Label
        ROI3Slider                   matlab.ui.control.Slider
        ROIWidthLabel                matlab.ui.control.Label
        ROI2Slider                   matlab.ui.control.Slider
        ROIUpLabel                   matlab.ui.control.Label
        ROILeftSlider                matlab.ui.control.Slider
        ROILeftSliderLabel           matlab.ui.control.Label
        CameraGammaSlider            matlab.ui.control.Slider
        CameraSharpnessSlider        matlab.ui.control.Slider
        CameraGammaSliderLabel       matlab.ui.control.Label
        CameraSharpnessSliderLabel   matlab.ui.control.Label
        CameraExposureSlider         matlab.ui.control.Slider
        CameraExposureSliderLabel    matlab.ui.control.Label
        CameraContrastSlider         matlab.ui.control.Slider
        CameraContrastSliderLabel    matlab.ui.control.Label
        CamerabrightnessSlider       matlab.ui.control.Slider
        CamerabrightnessSliderLabel  matlab.ui.control.Label
        AspectnumberEditField        matlab.ui.control.NumericEditField
        AspectnumberEditFieldLabel   matlab.ui.control.Label
        USBCameraDropDown            matlab.ui.control.DropDown
        USBCameraDropDownLabel       matlab.ui.control.Label
        NumberoflinesEditField       matlab.ui.control.NumericEditField
        NumberoflinesEditFieldLabel  matlab.ui.control.Label
        PortNumText                  matlab.ui.control.NumericEditField
        COMportEditFieldLabel        matlab.ui.control.Label
        OpenHypeSettingsLabel        matlab.ui.control.Label
        UIAxes_SnapShot              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        cam % USB camera object
        ROIA
        TerminateMeas =false; % global variable to control the termination of capturing
        i2cdac
        arduinoObj
        CamCOMSWidthA
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("Settings.mat")

            app.CamCOMSWidthA= CamCOMSWidth;
            app.NumberoflinesEditField.Value = NLines;
            app.PortNumText.Value = COMPort;
            app.USBCameraDropDown.Items{1} = WebCamName;
            app.NumberoflinesEditField.Value=NLines;
            app.CamerabrightnessSlider.Value=Brightness;
            app.EditField.Value = round(Brightness);
            app.CameraContrastSlider.Value=Contrast;
            app.EditField_2.Value = round(Contrast);
            app.CameraExposureSlider.Value=Exposure;
            app.EditField_3.Value = round(Exposure);
            app.CameraSharpnessSlider.Value=Sharpness;
            app.EditField_4.Value = round(Sharpness);

            app.CameraGammaSlider.Value=Gamma;
            app.EditField_5.Value = round(Gamma);


            app.ROILeftSlider.Value=ROI(1);
            app.ROI2Slider.Value=ROI(2);
            app.ROI3Slider.Value=ROI(3);
            app.ROIHeightSlider.Value=ROI(4);
            app.AspectnumberEditField.Value = Aspect;


        end

        % Button pushed function: ConnecttocameraButton
        function ConnecttocameraButtonPushed(app, event)
            load("Settings.mat")
            app.TerminateMeas= false;

            try
                app.cam = webcam(app.USBCameraDropDown.Value);
                sp = rgb2gray(snapshot(app.cam));
                app.ROILeftSlider.Limits=[0 size(sp,2)];
                app.CamCOMSWidthA= size(sp,2);
                app.ROI2Slider.Limits=[0 size(sp,1)];
            catch ME
                mydlg= errordlg(ME.message);
                waitfor(mydlg);
                app.TerminateMeas= true;
            end

            while(~app.TerminateMeas)
                try

                    app.cam.Brightness=round(app.CamerabrightnessSlider.Value);
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);

                end

                try
                    app.cam.Contrast=round(app.CameraContrastSlider.Value);
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);
                    app.TerminateMeas= true;

                end

                try
                    app.cam.Exposure=round(app.CameraExposureSlider.Value);
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);
                    app.TerminateMeas= true;
                end

                try
                    app.cam.Sharpness=round(app.CameraSharpnessSlider.Value);
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);
                    app.TerminateMeas= true;
                end

                try
                    app.cam.Gamma=round(app.CameraGammaSlider.Value);
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);
                    app.TerminateMeas= true;
                end

                try
                    app.cam.ExposureMode='manual';
                catch ME
                    mydlg= errordlg(ME.message);
                    waitfor(mydlg);
                    app.TerminateMeas= true;
                end


                if (app.TerminateMeas== true)
                    return
                    app.StopconnectionButton.Enable= 'off';
                    app.ConnecttocameraButton.Enable = 'on';
                else
                    app.StopconnectionButton.Enable= 'on';
                    app.ConnecttocameraButton.Enable = 'off';
                end

                sp = rgb2gray(snapshot(app.cam));

                app.ROI3Slider.Limits=[0 size(sp,2)-round(app.ROILeftSlider.Value)];
                app.ROIHeightSlider.Limits=[0 size(sp,1)-round(app.ROI2Slider.Value)];

                app.ROIA = [app.ROILeftSlider.Value,app.ROI2Slider.Value,app.ROI3Slider.Value,app.ROIHeightSlider.Value];
                sp=imcrop(sp,app.ROIA);
                imshow(sp,'Parent',app.UIAxes_SnapShot)
                drawnow;
                pause(0.1);
            end

        end

        % Drop down opening function: USBCameraDropDown
        function USBCameraDropDownOpening(app, event)
            camlist = webcamlist;
            app.USBCameraDropDown.Items=camlist;

        end

        % Button pushed function: StopconnectionButton
        function StopconnectionButtonPushed(app, event)
            app.TerminateMeas =true;
            app.StopconnectionButton.Enable = 'off';
            app.ConnecttocameraButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.TerminateMeas=true;
            delete(app)

        end

        % Value changed function: CamerabrightnessSlider
        function CamerabrightnessSliderValueChanged(app, event)
            value = app.CamerabrightnessSlider.Value;
            app.EditField.Value = round(value);

        end

        % Value changed function: CameraContrastSlider
        function CameraContrastSliderValueChanged(app, event)
            value = app.CameraContrastSlider.Value;
            app.EditField_2.Value = round(value);

        end

        % Value changed function: CameraExposureSlider
        function CameraExposureSliderValueChanged(app, event)
            value = app.CameraExposureSlider.Value;
            app.EditField_3.Value = round(value);
        end

        % Value changed function: CameraSharpnessSlider
        function CameraSharpnessSliderValueChanged(app, event)
            value = app.CameraSharpnessSlider.Value;
            app.EditField_4.Value = round(value);
        end

        % Value changed function: CameraGammaSlider
        function CameraGammaSliderValueChanged(app, event)
            value = app.CameraGammaSlider.Value;
            app.EditField_5.Value = round(value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.CamerabrightnessSlider.Value=value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.CameraContrastSlider.Value=value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.CameraExposureSlider.Value=value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.CameraSharpnessSlider.Value=value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.CameraGammaSlider.Value=value;
        end

        % Button pushed function: ConnecttocontrollerButton
        function ConnecttocontrollerButtonPushed(app, event)
            try
                app.arduinoObj = arduino(['COM',num2str(app.PortNumText.Value)],"Uno","Libraries","I2C");
                app.i2cdac = device(app.arduinoObj,'I2CAddress','0x62','bitrate',400000);
            catch ME
                errordlg(ME.message)
            end

            disp(app.arduinoObj)%%
            pause(1);
            disp(app.i2cdac)
            app.Lamp1.Color=[0.00,1.00,0.00];


        end

        % Value changed function: ManualdiflectionSlider
        function ManualdiflectionSliderValueChanged(app, event)

            value = app.ManualdiflectionSlider.Value;
            writeDigitalPin(app.arduinoObj,"D13",1); % arm the mirror controller
            ADCValue = 4096-((4096-1)/(100)*(value-1));
            write(app.i2cdac,ConverDAC(ADCValue));
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            COMPort = app.PortNumText.Value;
            ROI = [app.ROILeftSlider.Value,app.ROI2Slider.Value,app.ROI3Slider.Value,app.ROIHeightSlider.Value];
            WebCamName = app.USBCameraDropDown.Value;
            NLines = round(app.NumberoflinesEditField.Value);
            Aspect = round(app.AspectnumberEditField.Value);
            CamCOMSWidth = app.CamCOMSWidthA; % Number of pixels of the CMOS sensor
            Brightness = round(app.EditField.Value);
            Contrast =  round(app.EditField_2.Value);
            Exposure =  round(app.EditField_3.Value);
            Sharpness =  round(app.EditField_4.Value);
            Gamma =  round(app.EditField_5.Value);
            save("Settings.mat","COMPort","ROI","WebCamName","NLines","Aspect","CamCOMSWidth","Brightness","Contrast","Exposure","Sharpness","Gamma");
            app.Lamp1_2.Color=[0.00,1.00,0.00];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_SnapShot = uiaxes(app.UIFigure);
            title(app.UIAxes_SnapShot, 'Camera preview')
            xlabel(app.UIAxes_SnapShot, 'z-axis')
            ylabel(app.UIAxes_SnapShot, 'x-axis')
            app.UIAxes_SnapShot.XTick = [];
            app.UIAxes_SnapShot.YTick = [];
            app.OpenHypeSettingsLabel = uilabel(app.UIFigure);
            app.OpenHypeSettingsLabel.Text = 'OpenHype: Settings';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.PortNumText = uieditfield(app.UIFigure, 'numeric');
            app.PortNumText.Value = 21;
            app.NumberoflinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflinesEditFieldLabel.Text = 'Number of lines';
            app.NumberoflinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflinesEditField.Value = 50;
            app.USBCameraDropDownLabel = uilabel(app.UIFigure);
            app.USBCameraDropDownLabel.Text = 'USB Camera';
            app.USBCameraDropDown = uidropdown(app.UIFigure);
            app.USBCameraDropDown.DropDownOpeningFcn = createCallbackFcn(app, @USBCameraDropDownOpening, true);
            app.AspectnumberEditFieldLabel = uilabel(app.UIFigure);
            app.AspectnumberEditFieldLabel.Text = 'Aspect number';
            app.AspectnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectnumberEditField.Limits = [0 2000];
            app.AspectnumberEditField.Value = 500;
            app.CamerabrightnessSliderLabel = uilabel(app.UIFigure);
            app.CamerabrightnessSliderLabel.Text = 'Camera brightness ';
            app.CamerabrightnessSlider = uislider(app.UIFigure);
            app.CamerabrightnessSlider.Limits = [-100 100];
            app.CamerabrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @CamerabrightnessSliderValueChanged, true);
            app.CamerabrightnessSlider.Value = 0.8;
            app.CameraContrastSliderLabel = uilabel(app.UIFigure);
            app.CameraContrastSliderLabel.Text = 'Camera Contrast';
            app.CameraContrastSlider = uislider(app.UIFigure);
            app.CameraContrastSlider.Limits = [1 25];
            app.CameraContrastSlider.ValueChangedFcn = createCallbackFcn(app, @CameraContrastSliderValueChanged, true);
            app.CameraContrastSlider.Value = 1;
            app.CameraExposureSliderLabel = uilabel(app.UIFigure);
            app.CameraExposureSliderLabel.Text = 'Camera Exposure';
            app.CameraExposureSlider = uislider(app.UIFigure);
            app.CameraExposureSlider.Limits = [-10 5];
            app.CameraExposureSlider.ValueChangedFcn = createCallbackFcn(app, @CameraExposureSliderValueChanged, true);
            app.CameraExposureSlider.Value = 0.8;
            app.CameraSharpnessSliderLabel = uilabel(app.UIFigure);
            app.CameraSharpnessSliderLabel.Text = 'Camera Sharpness';
            app.CameraGammaSliderLabel = uilabel(app.UIFigure);
            app.CameraGammaSliderLabel.Text = 'Camera Gamma';
            app.CameraSharpnessSlider = uislider(app.UIFigure);
            app.CameraSharpnessSlider.Limits = [1 200];
            app.CameraSharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @CameraSharpnessSliderValueChanged, true);
            app.CameraSharpnessSlider.Value = 1;
            app.CameraGammaSlider = uislider(app.UIFigure);
            app.CameraGammaSlider.Limits = [1 500];
            app.CameraGammaSlider.ValueChangedFcn = createCallbackFcn(app, @CameraGammaSliderValueChanged, true);
            app.CameraGammaSlider.Value = 1;
            app.ROILeftSliderLabel = uilabel(app.UIFigure);
            app.ROILeftSliderLabel.Text = 'ROI - Left';
            app.ROILeftSlider = uislider(app.UIFigure);
            app.ROILeftSlider.Limits = [0 2000];
            app.ROILeftSlider.Value = 0.7;
            app.ROIUpLabel = uilabel(app.UIFigure);
            app.ROIUpLabel.Text = 'ROI - Up';
            app.ROI2Slider = uislider(app.UIFigure);
            app.ROI2Slider.Limits = [0 2000];
            app.ROI2Slider.Value = 0.7;
            app.ROIWidthLabel = uilabel(app.UIFigure);
            app.ROIWidthLabel.Text = 'ROI - Width';
            app.ROI3Slider = uislider(app.UIFigure);
            app.ROI3Slider.Limits = [0 2000];
            app.ROI3Slider.Value = 0.7;
            app.ROIHeightSliderLabel = uilabel(app.UIFigure);
            app.ROIHeightSliderLabel.Text = 'ROI - Height';
            app.ROIHeightSlider = uislider(app.UIFigure);
            app.ROIHeightSlider.Limits = [0 2000];
            app.ROIHeightSlider.Value = 0.7;
            app.SavesettingsButton = uibutton(app.UIFigure, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.ConnecttocameraButton = uibutton(app.UIFigure, 'push');
            app.ConnecttocameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttocameraButtonPushed, true);
            app.ConnecttocameraButton.Text = 'Connect to camera';
            app.WidthpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.WidthpixelsEditFieldLabel.Text = 'Width pixels';
            app.WidthpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthpixelsEditField.Limits = [0 2000];
            app.WidthpixelsEditField.Value = 500;
            app.StopconnectionButton = uibutton(app.UIFigure, 'push');
            app.StopconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @StopconnectionButtonPushed, true);
            app.StopconnectionButton.Enable = 'off';
            app.StopconnectionButton.Text = 'Stop connection';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.ConnecttocontrollerButton = uibutton(app.UIFigure, 'push');
            app.ConnecttocontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttocontrollerButtonPushed, true);
            app.ConnecttocontrollerButton.Text = 'Connect to controller';
            app.ManualdiflectionSliderLabel = uilabel(app.UIFigure);
            app.ManualdiflectionSliderLabel.Text = 'Manual diflection';
            app.ManualdiflectionSlider = uislider(app.UIFigure);
            app.ManualdiflectionSlider.ValueChangedFcn = createCallbackFcn(app, @ManualdiflectionSliderValueChanged, true);
            app.Lamp1 = uilamp(app.UIFigure);
            app.Lamp1_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenHypeSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end